classdef StabilityMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        RevertMenu                      matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        RunMenu                         matlab.ui.container.Menu
        RunMenu_2                       matlab.ui.container.Menu
        ParametersforRateEquationsPanel  matlab.ui.container.Panel
        NameLabel                       matlab.ui.control.Label
        ValueLabel                      matlab.ui.control.Label
        X_label                         matlab.ui.control.Label
        Y_label                         matlab.ui.control.Label
        alphaEditFieldLabel             matlab.ui.control.Label
        alpha                           matlab.ui.control.EditField
        cavitylossrate1nsEditFieldLabel  matlab.ui.control.Label
        kappa                           matlab.ui.control.EditField
        carrierlossrate1nsEditFieldLabel  matlab.ui.control.Label
        gamma                           matlab.ui.control.EditField
        couplingABEditFieldLabel        matlab.ui.control.Label
        couplingAB                      matlab.ui.control.EditField
        couplingBAEditFieldLabel        matlab.ui.control.Label
        couplingBA                      matlab.ui.control.EditField
        pumpBEditFieldLabel             matlab.ui.control.Label
        pumpB                           matlab.ui.control.EditField
        pumpALabel                      matlab.ui.control.Label
        pumpA                           matlab.ui.control.EditField
        couplingphaseradEditFieldLabel  matlab.ui.control.Label
        phase                           matlab.ui.control.EditField
        detuningradnsEditFieldLabel     matlab.ui.control.Label
        detuning                        matlab.ui.control.EditField
        alphaX                          matlab.ui.control.CheckBox
        kappaX                          matlab.ui.control.CheckBox
        gammaX                          matlab.ui.control.CheckBox
        couplingABX                     matlab.ui.control.CheckBox
        couplingBAX                     matlab.ui.control.CheckBox
        phaseX                          matlab.ui.control.CheckBox
        detuningX                       matlab.ui.control.CheckBox
        pumpAX                          matlab.ui.control.CheckBox
        pumpBX                          matlab.ui.control.CheckBox
        alphaY                          matlab.ui.control.CheckBox
        kappaY                          matlab.ui.control.CheckBox
        gammaY                          matlab.ui.control.CheckBox
        couplingABY                     matlab.ui.control.CheckBox
        couplingBAY                     matlab.ui.control.CheckBox
        phaseY                          matlab.ui.control.CheckBox
        detuningY                       matlab.ui.control.CheckBox
        pumpAY                          matlab.ui.control.CheckBox
        pumpBY                          matlab.ui.control.CheckBox
        EqualguidesCheckBox             matlab.ui.control.CheckBox
        EqualpumpsCheckBox              matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        ParameterRangePanel             matlab.ui.container.Panel
        xstart                          matlab.ui.control.EditField
        xend                            matlab.ui.control.EditField
        ystart                          matlab.ui.control.EditField
        yend                            matlab.ui.control.EditField
        xlabel                          matlab.ui.control.Label
        ylabel                          matlab.ui.control.Label
        startLabel                      matlab.ui.control.Label
        endLabel                        matlab.ui.control.Label
        GridpointsEditFieldLabel        matlab.ui.control.Label
        Gridpoints                      matlab.ui.control.EditField
        StabilityMapCoupledModeModelLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        RevertButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        isSaved = true  % All changes have been saved
        isData = false  % There is no data loaded or calculated
        NGrid = 31      % Default grid size
        
        aH      {mustBeNumeric}     % Linewidth enhancement factor
        kp      {mustBeNumeric}     % 1/(2*tau_p) cavity loss rate
        yn      {mustBeNumeric}     % 1/(tau_N) carrier recombination rate
        etaAB   {mustBeNumeric}     % Amplitude of coupling coefficient AB
        etaBA   {mustBeNumeric}     % Amplitude of coupling coefficient BA
        theta   {mustBeNumeric}     % Phase of coupling coefficient 
        DW      {mustBeNumeric}     % Detuning between the cavity resonances
        QA      {mustBeNumeric}     % Normalised pumping rate in guide A
        QB      {mustBeNumeric}     % Normalised pumping rate in guide B
        
        X       {mustBeNumeric}     % Grid for x-coordinate data
        Y       {mustBeNumeric}     % Grid for y-coordinate data
        Z       {mustBeNumeric}     % Grid for z-coordinate data
        
        x0      {mustBeNumeric}     % x coordinate start value
        xN      {mustBeNumeric}     % x coordinate end value
        y0      {mustBeNumeric}     % y coordinate start value
        yN      {mustBeNumeric}     % y coordinate end value
        
        % Index references to parameters
        ALPHA = 1;
        KAPPA = 2;
        GAMMA = 3;
        ETA_AB = 4;
        ETA_BA = 5;
        ETA_EQUAL = 6;
        THETA = 7;
        DOMEGA = 8;
        PUMP_A = 9;
        PUMP_B = 10;
        PUMP_EQUAL = 11;
        UNKNOWN = 12;
        
        xvar    {mustBeInteger}     % Index of parameter to vary on x
        yvar    {mustBeInteger}     % Index of parameter to vary on y
        
        labels = cell(10,1);        % Cell array of strings  
        
        
    end
    
    methods (Access = private)
        
        function falsifyX(app)
            
            % Ideally, I would like to replace this with a loop accessing
            % an array of pointers (if that is possible)
            
            if (app.alphaX.Value)
                app.alphaX.Value = false;
                app.alpha.Enable = true;
            end
            
            if (app.kappaX.Value)
                app.kappaX.Value = false;
                app.kappa.Enable = true;
            end
            
            if (app.gammaX.Value)
                app.gammaX.Value = false;
                app.gamma.Enable = true;
            end

            if (app.couplingABX.Value)
                app.couplingABX.Value = false;
                app.couplingAB.Enable = true;
            end
            
            if (app.couplingBAX.Value)
                app.couplingBAX.Value = false;
                app.couplingBA.Enable = true;
            end
            
            if (app.phaseX.Value)
                app.phaseX.Value = false;
                app.phase.Enable = true;
            end
            
            if (app.detuningX.Value)
                app.detuningX.Value = false;
                app.detuning.Enable = true;
            end
            
            if (app.pumpAX.Value)
                app.pumpAX.Value = false;
                app.pumpA.Enable = true;
            end
            
            if (app.pumpBX.Value)
                app.pumpBX.Value = false;
                app.pumpB.Enable = true;
            end
            
        end
        
        function falsifyY(app)
            
            % Ideally, I would like to replace this with a loop accessing
            % an array of pointers (if that is possible)
            
            if (app.alphaY.Value)
                app.alphaY.Value = false;
                app.alpha.Enable = true;
            end
            
            if (app.kappaY.Value)
                app.kappaY.Value = false;
                app.kappa.Enable = true;
            end
            
            if (app.gammaY.Value)
                app.gammaY.Value = false;
                app.gamma.Enable = true;
            end

            if (app.couplingABY.Value)
                app.couplingABY.Value = false;
                app.couplingAB.Enable = true;
            end
            
            if (app.couplingBAY.Value)
                app.couplingBAY.Value = false;
                app.couplingBA.Enable = true;
            end
            
            if (app.phaseY.Value)
                app.phaseY.Value = false;
                app.phase.Enable = true;
            end
            
            if (app.detuningY.Value)
                app.detuningY.Value = false;
                app.detuning.Enable = true;
            end
            
            if (app.pumpAY.Value)
                app.pumpAY.Value = false;
                app.pumpA.Enable = true;
            end
            
            if (app.pumpBY.Value)
                app.pumpBY.Value = false;
                app.pumpB.Enable = true;
            end
            
        end
        
        
        function loadData(app)
            
            [file,path] = uigetfile('*.mat');

            if not(isequal(file,0))
                
                try
                    
                    data = load(fullfile(path,file));
                    
                catch ME
                    
                    % Couldn't open file
                    msgbox(ME.message)
                    
                    return
                    
                end
                
                % Check data has correct fields
                if (not(isfield(data, 'X')) || not(isfield(data, 'Y')) || not(isfield(data, 'Z'))) 
                    
                    msgbox(['File ' file ' did not contain valid grid data'], 'Invalid grid data','error');
                    
                    
                else
                    
                    app.isData = true;
                    
                    app.X = data.X;
                    app.Y = data.Y;
                    app.Z = data.Z;
                    
                    app.plotData();
 
                end
                
            end
            
            % Hack to bring figure back to front
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
        end
        
        function plotData(app)
            
            if not(app.isData)
                return
            end
            
            % Create colour map
            values = app.Z;
            ncols = 11;
            
            maxv = max(values(:));
            minv = min(values(:));
            
            vspan = maxv-minv;
            dv = vspan/(ncols - 1);
            
            sample = minv:dv:maxv;
            neg_mask = (sample <= 0);
            pos_mask = (sample > 0);
            
            c0 = 0.4;
            
            red = (1-c0)*abs(sample.*pos_mask/maxv) + c0*pos_mask;
            green = (1-c0)*abs(sample.*neg_mask/minv) + c0*neg_mask;
            blue = zeros(size(sample));
            
            mymap = transpose([red; green; blue]);
            
            contourf(app.UIAxes, app.X, app.Y, app.Z, 'EdgeColor', 'none')
            colormap(app.UIAxes, mymap)
            colorbar(app.UIAxes)
            app.UIAxes.XLabel.String = app.labels(app.xvar);
            app.UIAxes.YLabel.String = app.labels(app.yvar);
               
        end
        
    end
    
    
    methods (Static)
        
        %   *** CORE FUNCTIONALITY ***
        
        function dN = rateEq(~, N0, param)
            % Rate equations for the coupled mode model. Dummy variable
            % stands in place of time for compatibility with Runge Kutta
            % solver (ode45) or equivalent
            %
            % Arguments:
            %
            %   N0      array of intial values
            %   param   structure containing parameters
            %
            % Returns:
            %
            %   dN      array containing rates of change
            
            MA = N0(1);         % Carrier concentration in guide A
            MB = N0(2);         % Carrier concentration in guide A
            YA = N0(3);         % Amplitude in guide A
            YB = N0(4);         % Amplitude in guide B
            phi = N0(5);        % Relative phase between fields in A and B
        
            % Intensities (all values real)
            IA = YA*YA;
            IB = YB*YB;
        
            yn = param.yn;          % 1/(tau_N) - carrier recombination rate
            kp = param.kp;          % 1/(2*tau_p) - cavity loss rate
            aH = param.aH;          % Linewidth enhancement factor
            QA = param.QA;          % Normalised pumping rate in guide A    
            QB = param.QB;          % Normalised pumping rate in guide A 
            etaAB = param.etaAB;    % Amplitude of coupling coefficient AB
            etaBA = param.etaBA;    % Amplitude of coupling coefficient BA
            theta = param.theta;    % Phase of coupling coefficient 
            DW = param.DW;          % Detuning between the cavity resonances
        
            dN = zeros(size(N0));
        
            % dMA/dt:
            dN(1) = yn*(QA - MA*(1.0 + IA));
        
            % dMB/dt:
            dN(2) = yn*(QB - MB*(1.0 + IB));
        
            % dYA/dt:
            dN(3) = kp*(MA - 1.0)*YA - etaAB*YB*sin(theta + phi);
        
            % dYB/dt:
            dN(4) = kp*(MB - 1.0)*YB - etaBA*YA*sin(theta - phi);
        
            % dphi/dt: 
            dN(5) = aH*kp*(MA - MB) - DW + etaAB*(YA/YB)*cos(theta - phi)...
                - etaBA*(YB/YA)*cos(theta + phi);
            
        end
        
        function dN = rateEqS(N0, param)
            % Autonomous (time independent) version of the rate equations
            % for use with fsolve to find the steady state solutions
            %
            % Arguments:
            %
            %   N0      array of intial values
            %   param   structure containing parameters
            %
            % Returns:
            %
            %   dN      array containing rates of change
            
            dN = StabilityMap.rateEq(0, N0, param);
            
        end
        
        function [Ns, found, E, esign] = solve(N0, param)
            % Finds steady state solutions of rate equations using a
            % nonlinear solver (fsolve)
            %
            % Arguments:
            %
            %   N0      array of intial values
            %   param   structure containing parameters
            %
            % Returns:
            %
            %   Ns      array containing the steady state solutions
            %
            %	found   Boolean indicating solutions found
            %
            %   E       Eigenvalues of Jacobian
            %
            %   esign   -1 indicates stable solution, 1 indicates unstable solution
            
            % Anonymous handle to function including passed parameters   
            fun = @(x) StabilityMap.rateEqS(x, param);    
            options = optimoptions('fsolve','Display','off');
            
            found = false;
        
            [Ns,~,xstat,~,J] = fsolve(fun, N0, options);
        
            if (xstat > 0)
        
                found = true;
        
                % Find eigenvalues of Jacobian
                [E] = eig(J);
        
                Etest = E;
                tol = 1E-6;
        
                % Check for zero eigenvalues
                for n = 1:length(E)
        
                    if ((E(n) == 0.0)||(abs(E(n)) < tol))
        
                        % Make these negative
                        Etest(n) = -1.0;
        
                    end
        
                end
        
                % Find maximum real component of the eigenvalues.
                % For stable solutions, this will be negative.
                Er = real(Etest);
                Emax = max(Er);
        
                if (Emax < 0.0)
        
                    esign = -1; % Stable solution
        
                else
        
                    esign = 1;  % Unstable solution
        
                end
        
            else
        
                % *** NO SOLUTION FOUND ***
                varnum = length(N0);
                E = zeros(varnum,1);
                esign = 0.0;
                Ns = N0;
        
            end
            
        end    
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
            
            % Initialize array of labels
            app.labels{app.ALPHA} = 'alpha';
            app.labels{app.KAPPA} = 'cavity loss rate (1/ns)';
            app.labels{app.GAMMA} = 'carrier loss rate (1/ns)';
            app.labels{app.ETA_AB} = 'coupling (AB)';
            app.labels{app.ETA_BA} = 'coupling (BA)';
            app.labels{app.ETA_EQUAL} = 'coupling (equal)';
            app.labels{app.THETA} = 'coupling phase (rad)';
            app.labels{app.DOMEGA} = 'detuning (rad/ns)';
            app.labels{app.PUMP_A} = 'pump (A)';
            app.labels{app.PUMP_B} = 'pump (B)';
            app.labels{app.PUMP_EQUAL} = 'pump (B)';
            app.labels{app.UNKNOWN} = '?';
            
            % N.B. String literals have been used in various callback
            % functions - this should be changed at some point to be 
            % assigned from the labels array.
 
            % Hard-coded default values
            app.aH = 2.0;           % Linewidth enhancement factor
            app.kp = 326.7974;      % Cavity loss rate 1/(2*tau_p) (1/ns)
            app.yn = 1.0;           % 1/tau_N, where tau_N is the carrier lifetime
            app.etaAB = 1.0;        % Amplitude of coupling coefficient AB
            app.etaBA = 1.0;        % Amplitude of coupling coefficient BA
            app.theta = 0.0;        % Phase of coupling coefficient
            app.DW  = 0.0;          % Detuning
            app.QA = 10;            % Normalised pump power in guide A
            app.QB = 10;            % Normalised pump power in guide B
            
            app.x0 = 1;             % x coordinate start value
            app.xN = 2;             % x coordinate end value
            app.y0 = 1;             % y coordinate start value
            app.yN = 2;             % y coordinate end value
            
            app.xvar = app.ETA_EQUAL;   % Variable to plot on x-axis
            app.yvar = app.DOMEGA;      % Variable to plot on y-axis
            
            % Initialize edit controls with default values
            app.alpha.Value = num2str(app.aH);           
            app.kappa.Value = num2str(app.kp);    
            app.gamma.Value = num2str(app.yn);         
            app.couplingAB.Value = num2str(app.etaAB);      
            app.couplingBA.Value = num2str(app.etaBA);      
            app.phase.Value = num2str(app.theta);      
            app.detuning.Value = num2str(app.DW);         
            app.pumpA.Value = num2str(app.QA);          
            app.pumpB.Value = num2str(app.QB);
            
            % Set initial map parameters
            app.xstart.Value = num2str(app.x0);
            app.xend.Value = num2str(app.xN);
            app.ystart.Value = num2str(app.y0);
            app.yend.Value = num2str(app.yN);

            % Set labels
            switch app.xvar
                
                case app.ALPHA
                    
                    app.xlabel.Text = app.labels(app.ALPHA);
                    app.alpha.Enable = false;
                    app.alphaX.Value = true;
                    
                case app.KAPPA
                    
                    app.xlabel.Text = app.labels(app.KAPPA);
                    app.kappa.Enable = false;
                    app.kappaX.Value = true;
                    
                case app.GAMMA
                    
                    app.xlabel.Text = app.labels(app.GAMMA);
                    app.gamma.Enable = false;
                    app.gammaX.Value = true;
                    
                case app.ETA_AB
                    
                    app.xlabel.Text = app.labels(app.ETA_AB);
                    app.couplingAB.Enable = false;
                    app.couplingABX.Value = true;
                    
                case app.ETA_BA
                    
                    app.xlabel.Text = app.labels(app.ETA_BA);
                    app.couplingBA.Enable = false;
                    app.couplingBAX.Value = true;
                    
                case app.ETA_EQUAL
                    
                    app.xlabel.Text = app.labels(app.ETA_EQUAL);
                    app.couplingAB.Enable = false;
                    app.couplingBA.Enable = false;
                    app.couplingABX.Value = true;
                    
                case app.THETA
                    
                    app.xlabel.Text = app.labels(app.THETA);
                    app.theta.Enable = false;
                    app.thetaX.Value = true;
                    
                case app.DOMEGA
                    
                    app.xlabel.Text = app.labels(app.DOMEGA);
                    app.detuning.Enable = false;
                    app.detuningX.Value = true;
                    
                case app.PUMP_A
                    
                    app.xlabel.Text = app.labels(app.PUMP_A);
                    app.pumpA.Enable = false;
                    app.pumpAX.Value = true;
                    
                case app.PUMP_B
                    
                    app.xlabel.Text = app.labels(app.PUMP_B);
                    app.pumpB.Enable = false;
                    app.pumpBX.Value = true;
                    
                case app.PUMP_EQUAL
                    
                    app.xlabel.Text = app.labels(app.PUMP_EQUAL);
                    app.pumpA.Enable = false;
                    app.pumpB.Enable = false;
                    app.pumpAX.Value = true;
                    
                otherwise
                    
                    app.xlabel.Text = app.labels(app.UNKNOWN);
            end
            
            switch app.yvar
                
                case app.ALPHA
                    
                    app.ylabel.Text = app.labels(app.ALPHA);
                    app.alpha.Enable = false;
                    app.alphaY.Value = true;
                    
                case app.KAPPA
                    
                    app.ylabel.Text = app.labels(app.KAPPA);
                    app.kappa.Enable = false;
                    app.kappaY.Value = true;
                    
                case app.GAMMA
                    
                    app.ylabel.Text = app.labels(app.GAMMA);
                    app.gamma.Enable = false;
                    app.gammaY.Value = true;
                    
                case app.ETA_AB
                    
                    app.ylabel.Text = app.labels(app.ETA_AB);
                    app.couplingAB.Enable = false;
                    app.couplingABY.Value = true;
                    
                case app.ETA_BA
                    
                    app.ylabel.Text = app.labels(app.ETA_BA);
                    app.couplingBA.Enable = false;
                    app.couplingBAY.Value = true;
                    
                case app.ETA_EQUAL
                    
                    app.ylabel.Text = app.labels(app.ETA_EQUAL);
                    app.couplingAB.Enable = false;
                    app.couplingBA.Enable = false;
                    app.couplingABY.Value = true;
                    
                case app.THETA
                    
                    app.ylabel.Text = app.labels(app.THETA);
                    app.theta.Enable = false;
                    app.thetaY.Value = true;
                    
                case app.DOMEGA
                    
                    app.ylabel.Text = app.labels(app.DOMEGA);
                    app.detuning.Enable = false;
                    app.detuningY.Value = true;
                    
                case app.PUMP_A
                    
                    app.ylabel.Text = app.labels(app.PUMP_A);
                    app.pumpA.Enable = false;
                    app.pumpAY.Value = true;
                    
                case app.PUMP_B
                    
                    app.ylabel.Text = app.labels(app.PUMP_B);
                    app.pumpB.Enable = false;
                    app.pumpBY.Value = true;
                    
                case app.PUMP_EQUAL
                    
                    app.ylabel.Text = app.labels(app.PUMP_EQUAL);
                    app.pumpA.Enable = false;
                    app.pumpB.Enable = false;
                    app.pumpAY.Value = true;
                    
                otherwise
                    
                    app.ylabel.Text = app.labels(app.UNKNOWN);
            end
               
            % Set equal guides checkbox
            if (app.etaAB == app.etaBA)
                
                app.EqualguidesCheckBox.Value = true;
                app.couplingBA.Enable = false;
                app.couplingBAX.Enable = false;
                app.couplingBAY.Enable = false;
                
            end

            % Set equal pumps checkbox
            if (app.QA == app.QB)
                
                app.EqualpumpsCheckBox.Value = true;
                app.pumpB.Enable = false;
                app.pumpBX.Enable = false;
                app.pumpBY.Enable = false;
                
            end
            
            % Grid points
            app.Gridpoints.Value = num2str(app.NGrid);
           
            
        end

        % Value changed function: kappaX
        function kappaXValueChanged(app, event)
            
            value = app.kappaX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.kappaX.Value = true;
                app.kappa.Enable = false;
                app.xlabel.Text = 'cavity loss rate (1/ns)';
                
                % Check corresponding Y checkbox
                if (app.kappaY.Value == true)
                    
                    app.kappaY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.kappa.Enable = true;
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: gammaX
        function gammaXValueChanged(app, event)
            
            value = app.gammaX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.gammaX.Value = true;
                app.gamma.Enable = false;
                app.xlabel.Text = 'carrier loss rate (1/ns)';
                
                % Check corresponding Y checkbox
                if (app.gammaY.Value == true)
                    
                    app.gammaY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.gamma.Enable = true;
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: couplingABX
        function couplingABXValueChanged(app, event)
            
            value = app.couplingABX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.couplingABX.Value = true;
                app.couplingAB.Enable = false;
                
                % Check corresponding Y checkbox
                if (app.couplingABY.Value == true)
                    
                    app.couplingABY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
                if (app.EqualguidesCheckBox.Value)
                    
                    app.xlabel.Text = 'coupling (equal)';
                    
                else
                    
                    app.xlabel.Text = 'coupling (AB)';
                    
                end   
                
            else
                
                app.couplingAB.Enable = true;
                
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: couplingBAX
        function couplingBAXValueChanged(app, event)
            
            value = app.couplingBAX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.couplingBAX.Value = true;
                app.couplingBA.Enable = false;
                
                app.xlabel.Text = 'coupling (BA)';
                
                % Check corresponding Y checkbox
                if (app.couplingBAY.Value == true)
                    
                    app.couplingBAY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                                
            else
                
                app.couplingBA.Enable = true;
                
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: phaseX
        function phaseXValueChanged(app, event)
            
            value = app.phaseX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.phaseX.Value = true;
                app.phase.Enable = false;
                app.xlabel.Text = 'coupling phase (rad)';
                
                % Check corresponding Y checkbox
                if (app.phaseY.Value == true)
                    
                    app.phaseY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.phase.Enable = true;
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: detuningX
        function detuningXValueChanged(app, event)
            
            value = app.detuningX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.detuningX.Value = true;
                app.detuning.Enable = false;
                app.xlabel.Text = 'detuning (rad/ns)';
                
                % Check corresponding Y checkbox
                if (app.detuningY.Value == true)
                    
                    app.detuningY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.detuning.Enable = true;
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: pumpAX
        function pumpAXValueChanged(app, event)
            
            value = app.pumpAX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.pumpAX.Value = true;
                app.pumpA.Enable = false;
                
                % Check corresponding Y checkbox
                if (app.pumpAY.Value == true)
                    
                    app.pumpAY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
                if (app.EqualpumpsCheckBox.Value)
                    
                    app.xlabel.Text = 'pump (equal)';
                    
                else
                    
                    app.xlabel.Text = 'pump (A)';
                    
                end   
                
            else
                
                app.pumpA.Enable = true;
                
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: pumpBX
        function pumpBXValueChanged(app, event)
            
            value = app.pumpBX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.pumpBX.Value = true;
                app.pumpB.Enable = false;
                
                % Check corresponding Y checkbox
                if (app.pumpBY.Value == true)
                    
                    app.pumpBY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.pumpB.Enable = true;
                
                app.xlabel.Text = '?';
                
            end
            
        end

        % Callback function: LoadButton, LoadMenu
        function LoadButtonPushed(app, event)
            
            app.loadData();
            
        end

        % Value changed function: alphaY
        function alphaYValueChanged(app, event)
            
            value = app.alphaY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.alphaY.Value = true;
                app.alpha.Enable = false;
                app.ylabel.Text = 'alpha';
                
                % Check corresponding Y checkbox
                if (app.alphaX.Value == true)
                    
                    app.alphaX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.alpha.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: kappaY
        function kappaYValueChanged(app, event)
            
            value = app.kappaY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.kappaY.Value = true;
                app.kappa.Enable = false;
                app.ylabel.Text = 'cavity loss rate (1/ns)';
                
                % Check corresponding Y checkbox
                if (app.kappaX.Value == true)
                    
                    app.kappaX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.kappa.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: gammaY
        function gammaYValueChanged(app, event)
            
            value = app.gammaY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.gammaY.Value = true;
                app.gamma.Enable = false;
                app.ylabel.Text = 'carrier loss rate (1/ns)';
                
                % Check corresponding Y checkbox
                if (app.gammaX.Value == true)
                    
                    app.gammaX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.gamma.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: couplingABY
        function couplingABYValueChanged(app, event)
            
            value = app.couplingABY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.couplingABY.Value = true;
                app.couplingAB.Enable = false;
                
                if (app.EqualguidesCheckBox.Value)
                    
                    app.couplingBA.Enable = false;
                    
                    app.ylabel.Text = 'coupling (equal)';
                    
                else
                    
                    app.ylabel.Text = 'coupling (AB)';
                    
                end 
                
                % Check corresponding Y checkbox
                if (app.couplingABX.Value == true)
                    
                    app.couplingABX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.couplingAB.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: couplingBAY
        function couplingBAYValueChanged(app, event)
            
            value = app.couplingBAY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.couplingBAY.Value = true;
                app.couplingBA.Enable = false;
                app.ylabel.Text = 'coupling (BA)';
                
                % Check corresponding Y checkbox
                if (app.couplingBAX.Value == true)
                    
                    app.couplingBAX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.couplingBA.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: phaseY
        function phaseYValueChanged(app, event)
            
            value = app.phaseY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.phaseY.Value = true;
                app.phase.Enable = false;
                app.ylabel.Text = 'coupling phase (rad)';
                
                % Check corresponding Y checkbox
                if (app.phaseX.Value == true)
                    
                    app.phaseX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.phase.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: detuningY
        function detuningYValueChanged(app, event)
            
            value = app.detuningY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.detuningY.Value = true;
                app.detuning.Enable = false;
                app.ylabel.Text = 'detuning (rad/ns)';
                
                % Check corresponding Y checkbox
                if (app.detuningX.Value == true)
                    
                    app.detuningX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.detuning.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: pumpAY
        function pumpAYValueChanged(app, event)
            
            value = app.pumpAY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.pumpAY.Value = true;
                app.pumpA.Enable = false;
                
                if (app.EqualpumpsCheckBox.Value)
                    
                    app.ylabel.Text = 'pump (equal)';
                    
                else
                    
                    app.ylabel.Text = 'pump (A)';
                    
                end 
                
                % Check corresponding Y checkbox
                if (app.pumpAX.Value == true)
                    
                    app.pumpAX.Value = false;
                    app.xlabel.Text = '?';
                    
                end

            else
                
                app.pumpA.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: pumpBY
        function pumpBYValueChanged(app, event)
            
            value = app.pumpBY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.pumpBY.Value = true;
                app.pumpB.Enable = false;
                app.ylabel.Text = 'pump (B)';
                
                % Check corresponding Y checkbox
                if (app.pumpBX.Value == true)
                    
                    app.pumpBX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
                
            else
                
                app.pumpB.Enable = true; 
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: alpha
        function alphaValueChanged(app, event)
            
            value = app.alpha.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.alpha.Value = num2str(app.aH);
                
            else
                
                % Set numerical property
                app.aH = str2double(value);        
                
            end
            
        end

        % Value changed function: kappa
        function kappaValueChanged(app, event)
            
            value = app.kappa.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.kappa.Value = num2str(app.kp);
                
            else
                
                % Set numerical property
                app.kp = str2double(value); 
                
            end
        end

        % Value changed function: gamma
        function gammaValueChanged(app, event)
            
            value = app.gamma.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.gamma.Value = num2str(app.yn);
                
            else
                
                % Set numerical property
                app.yn = str2double(value);
                
            end
        end

        % Value changed function: couplingAB
        function couplingABValueChanged(app, event)
            
            value = app.couplingAB.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')

                % Set value back to original
                app.couplingAB.Value = num2str(app.etaAB);
                
            else
                
                % Set numerical property
                app.etaAB = str2double(value);
                
                if (app.EqualguidesCheckBox.Value)
                    
                    app.couplingBA.Value = value;  
                    app.etaBA = str2double(value);
                    
                end
                
            end
            
        end

        % Value changed function: couplingBA
        function couplingBAValueChanged(app, event)
            
            value = app.couplingBA.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.couplingBA.Value = num2str(app.etaBA);
                
            else
                
                % Set numerical property
                app.etaBA = str2double(value);
                      
            end
            
        end

        % Value changed function: pumpB
        function pumpBValueChanged(app, event)
            
            value = app.pumpB.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.pumpB.Value = num2str(app.QB);
                
            else
                
                % Set numerical property
                app.QB = str2double(value);
                
            end
            
        end

        % Value changed function: pumpA
        function pumpAValueChanged(app, event)
            
            value = app.pumpA.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.pumpA.Value = num2str(app.QA);
                
            else
                
                % Set numerical property
                app.QA = str2double(value);
                
                if (app.EqualpumpsCheckBox.Value)
                    
                    app.pumpB.Value = value;
                    app.QB = str2double(value);
                    
                end
                
            end
            
        end

        % Value changed function: phase
        function phaseValueChanged(app, event)
            
            value = app.phase.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.phase.Value = num2str(app.theta);
                
            else
                
                % Set numerical property
                app.theta = str2double(value);
                
            end
            
        end

        % Value changed function: detuning
        function detuningValueChanged(app, event)
            
            value = app.detuning.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.detuning.Value = num2str(app.DW);
                
            else
                
                % Set numerical property
                app.DW = str2double(value);
                
            end
            
        end

        % Value changed function: alphaX
        function alphaXValueChanged(app, event)
            
            value = app.alphaX.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyX();
                app.alphaX.Value = true;
                app.alpha.Enable = false;
                app.xlabel.Text = 'alpha';
                
                % Check corresponding Y checkbox
                if (app.alphaY.Value == true)
                    
                    app.alphaY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.alpha.Enable = true;
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: xstart
        function xstartValueChanged(app, event)
            
            value = app.xstart.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.xstart.Value = num2str(app.x0);
                
            else
                
                % Set numerical property
                app.x0 = str2double(value);
                
            end
            
        end

        % Value changed function: xend
        function xendValueChanged(app, event)
            
            value = app.xend.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.xend.Value = num2str(app.xN);
                
            else
                
                % Set numerical property
                app.xN = str2double(value);
                
            end
            
        end

        % Value changed function: ystart
        function ystartValueChanged(app, event)
            
            value = app.ystart.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.ystart.Value = num2str(app.y0);
                
            else
                
                % Set numerical property
                app.y0 = str2double(value);
                
            end
            
        end

        % Value changed function: yend
        function yendValueChanged(app, event)
            
            value = app.yend.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.yend.Value = num2str(app.yN);
                
            else
                
                % Set numerical property
                app.yN = str2double(value);
                
            end
            
        end

        % Value changed function: Gridpoints
        function GridpointsValueChanged(app, event)
            
            value = app.Gridpoints.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
            else
                
                N = floor(str2double(value));
                
                if (N < 2)
                    
                    msgbox('Number of grid points must be an integer greater than 1')
                    
                else
                    
                    app.Gridpoints.Value = num2str(N);
                    
                end               
                
            end
            
        end

        % Value changed function: EqualguidesCheckBox
        function EqualguidesCheckBoxValueChanged(app, event)
            
            value = app.EqualguidesCheckBox.Value;
            
            if (value)
                
                % Enforce equal guides
                app.couplingBA.Value = app.couplingAB.Value;
                app.etaBA = app.etaAB;
                
                % Check for checked guides
                if (app.couplingBAX.Value)
                    
                    app.couplingBAX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
                if (app.couplingBAY.Value)
                    
                    app.couplingBAY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
                app.couplingBA.Enable = false;
                app.couplingBAX.Enable = false;
                app.couplingBAY.Enable = false;
                      
            else
                
                % Allow unequal guides
                app.couplingBA.Enable = true;
                app.couplingBAX.Enable = true;
                app.couplingBAY.Enable = true;
                
            end
            
        end

        % Value changed function: EqualpumpsCheckBox
        function EqualpumpsCheckBoxValueChanged(app, event)
            
            value = app.EqualpumpsCheckBox.Value;
            
            if (value)
                
                % Enforce equal guides
                app.pumpB.Value = app.pumpA.Value;
                app.QB = app.QA;
                
                % Check for checked guides
                if (app.pumpBX.Value)
                    
                    app.pumpBX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
                if (app.pumpBY.Value)
                    
                    app.pumpBY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
                app.pumpB.Enable = false;
                app.pumpBX.Enable = false;
                app.pumpBY.Enable = false;
                      
            else
                
                % Allow unequal guides
                app.pumpB.Enable = true;
                app.pumpBX.Enable = true;
                app.pumpBY.Enable = true;
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataMenu = uimenu(app.UIFigure);
            app.DataMenu.Text = 'Data';
            app.LoadMenu = uimenu(app.DataMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadMenu.Accelerator = 'l';
            app.LoadMenu.Text = 'Load';
            app.RevertMenu = uimenu(app.DataMenu);
            app.RevertMenu.Accelerator = 'z';
            app.RevertMenu.Text = 'Revert';
            app.SaveMenu = uimenu(app.DataMenu);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.RunMenu = uimenu(app.UIFigure);
            app.RunMenu.Text = 'Run';
            app.RunMenu_2 = uimenu(app.RunMenu);
            app.RunMenu_2.Accelerator = 'r';
            app.RunMenu_2.Text = 'Run';
            app.ParametersforRateEquationsPanel = uipanel(app.UIFigure);
            app.ParametersforRateEquationsPanel.Title = 'Parameters for Rate Equations';
            app.NameLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.NameLabel.Text = {'Name'; ''};
            app.ValueLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.ValueLabel.Text = {'Value'; ''};
            app.X_label = uilabel(app.ParametersforRateEquationsPanel);
            app.X_label.Text = {'X'; ''};
            app.Y_label = uilabel(app.ParametersforRateEquationsPanel);
            app.Y_label.Text = {'Y'; ''};
            app.alphaEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alpha = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.alpha.ValueChangedFcn = createCallbackFcn(app, @alphaValueChanged, true);
            app.alpha.Tooltip = {'Linewidth enhancement factor'};
            app.cavitylossrate1nsEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.cavitylossrate1nsEditFieldLabel.Text = 'cavity loss rate (1/ns)';
            app.kappa = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.kappa.ValueChangedFcn = createCallbackFcn(app, @kappaValueChanged, true);
            app.kappa.Tooltip = {'1/(2*tp), where tp is the photon lifetime'};
            app.carrierlossrate1nsEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.carrierlossrate1nsEditFieldLabel.Text = 'carrier loss rate (1/ns)';
            app.gamma = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.gamma.ValueChangedFcn = createCallbackFcn(app, @gammaValueChanged, true);
            app.gamma.Tooltip = {'1/tN, were tN is the carrier lifetime'};
            app.couplingABEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.couplingABEditFieldLabel.Text = 'coupling (AB)';
            app.couplingAB = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.couplingAB.ValueChangedFcn = createCallbackFcn(app, @couplingABValueChanged, true);
            app.couplingAB.Tooltip = {'amplitude of coupling coefficient (AB)'};
            app.couplingBAEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.couplingBAEditFieldLabel.Text = 'coupling (BA)';
            app.couplingBA = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.couplingBA.ValueChangedFcn = createCallbackFcn(app, @couplingBAValueChanged, true);
            app.couplingBA.Tooltip = {'amplitude of coupling coefficient (BA)'};
            app.pumpBEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.pumpBEditFieldLabel.Text = 'pump (B)';
            app.pumpB = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.pumpB.ValueChangedFcn = createCallbackFcn(app, @pumpBValueChanged, true);
            app.pumpB.Tooltip = {'normalised pumping in guide B'};
            app.pumpALabel = uilabel(app.ParametersforRateEquationsPanel);
            app.pumpALabel.Text = 'pump (A)';
            app.pumpA = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.pumpA.ValueChangedFcn = createCallbackFcn(app, @pumpAValueChanged, true);
            app.pumpA.Tooltip = {'normalised pumping in guide A'};
            app.couplingphaseradEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.couplingphaseradEditFieldLabel.Text = 'coupling phase (rad)';
            app.phase = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.phase.ValueChangedFcn = createCallbackFcn(app, @phaseValueChanged, true);
            app.phase.Tooltip = {'phase of coupling coefficient'};
            app.detuningradnsEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.detuningradnsEditFieldLabel.Text = 'detuning (rad/ns)';
            app.detuning = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.detuning.ValueChangedFcn = createCallbackFcn(app, @detuningValueChanged, true);
            app.detuning.Tooltip = {'laser detuning in rad/ns - N.B. NOT GHz'};
            app.alphaX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.alphaX.ValueChangedFcn = createCallbackFcn(app, @alphaXValueChanged, true);
            app.alphaX.Tooltip = {'select this parameter to vary along x axis'};
            app.alphaX.Text = '';
            app.kappaX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.kappaX.ValueChangedFcn = createCallbackFcn(app, @kappaXValueChanged, true);
            app.kappaX.Tooltip = {'select this parameter to vary along x axis'};
            app.kappaX.Text = '';
            app.gammaX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.gammaX.ValueChangedFcn = createCallbackFcn(app, @gammaXValueChanged, true);
            app.gammaX.Tooltip = {'select this parameter to vary along x axis'};
            app.gammaX.Text = '';
            app.couplingABX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.couplingABX.ValueChangedFcn = createCallbackFcn(app, @couplingABXValueChanged, true);
            app.couplingABX.Tooltip = {'select this parameter to vary along x axis'};
            app.couplingABX.Text = '';
            app.couplingBAX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.couplingBAX.ValueChangedFcn = createCallbackFcn(app, @couplingBAXValueChanged, true);
            app.couplingBAX.Tooltip = {'select this parameter to vary along x axis'};
            app.couplingBAX.Text = '';
            app.phaseX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.phaseX.ValueChangedFcn = createCallbackFcn(app, @phaseXValueChanged, true);
            app.phaseX.Tooltip = {'select this parameter to vary along x axis'};
            app.phaseX.Text = '';
            app.detuningX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.detuningX.ValueChangedFcn = createCallbackFcn(app, @detuningXValueChanged, true);
            app.detuningX.Tooltip = {'select this parameter to vary along x axis'};
            app.detuningX.Text = '';
            app.pumpAX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.pumpAX.ValueChangedFcn = createCallbackFcn(app, @pumpAXValueChanged, true);
            app.pumpAX.Tooltip = {'select this parameter to vary along x axis'};
            app.pumpAX.Text = '';
            app.pumpBX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.pumpBX.ValueChangedFcn = createCallbackFcn(app, @pumpBXValueChanged, true);
            app.pumpBX.Tooltip = {'select this parameter to vary along x axis'};
            app.pumpBX.Text = '';
            app.alphaY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.alphaY.ValueChangedFcn = createCallbackFcn(app, @alphaYValueChanged, true);
            app.alphaY.Tooltip = {'select this parameter to vary along y axis'};
            app.alphaY.Text = '';
            app.kappaY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.kappaY.ValueChangedFcn = createCallbackFcn(app, @kappaYValueChanged, true);
            app.kappaY.Tooltip = {'select this parameter to vary along y axis'};
            app.kappaY.Text = '';
            app.gammaY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.gammaY.ValueChangedFcn = createCallbackFcn(app, @gammaYValueChanged, true);
            app.gammaY.Tooltip = {'select this parameter to vary along y axis'};
            app.gammaY.Text = '';
            app.couplingABY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.couplingABY.ValueChangedFcn = createCallbackFcn(app, @couplingABYValueChanged, true);
            app.couplingABY.Tooltip = {'select this parameter to vary along y axis'};
            app.couplingABY.Text = '';
            app.couplingBAY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.couplingBAY.ValueChangedFcn = createCallbackFcn(app, @couplingBAYValueChanged, true);
            app.couplingBAY.Tooltip = {'select this parameter to vary along y axis'};
            app.couplingBAY.Text = '';
            app.phaseY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.phaseY.ValueChangedFcn = createCallbackFcn(app, @phaseYValueChanged, true);
            app.phaseY.Tooltip = {'select this parameter to vary along y axis'};
            app.phaseY.Text = '';
            app.detuningY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.detuningY.ValueChangedFcn = createCallbackFcn(app, @detuningYValueChanged, true);
            app.detuningY.Tooltip = {'select this parameter to vary along y axis'};
            app.detuningY.Text = '';
            app.pumpAY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.pumpAY.ValueChangedFcn = createCallbackFcn(app, @pumpAYValueChanged, true);
            app.pumpAY.Tooltip = {'select this parameter to vary along y axis'};
            app.pumpAY.Text = '';
            app.pumpBY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.pumpBY.ValueChangedFcn = createCallbackFcn(app, @pumpBYValueChanged, true);
            app.pumpBY.Tooltip = {'select this parameter to vary along y axis'};
            app.pumpBY.Text = '';
            app.EqualguidesCheckBox = uicheckbox(app.ParametersforRateEquationsPanel);
            app.EqualguidesCheckBox.ValueChangedFcn = createCallbackFcn(app, @EqualguidesCheckBoxValueChanged, true);
            app.EqualguidesCheckBox.Tooltip = {'When checked, coupling (AB) will be varied with coupling (BA)'};
            app.EqualguidesCheckBox.Text = 'Equal guides';
            app.EqualpumpsCheckBox = uicheckbox(app.ParametersforRateEquationsPanel);
            app.EqualpumpsCheckBox.ValueChangedFcn = createCallbackFcn(app, @EqualpumpsCheckBoxValueChanged, true);
            app.EqualpumpsCheckBox.Tooltip = {'When checked, pump (A) will be varied with pump (B)'};
            app.EqualpumpsCheckBox.Text = 'Equal pumps';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ParameterRangePanel = uipanel(app.UIFigure);
            app.ParameterRangePanel.Title = 'Parameter Range';
            app.xstart = uieditfield(app.ParameterRangePanel, 'text');
            app.xstart.ValueChangedFcn = createCallbackFcn(app, @xstartValueChanged, true);
            app.xend = uieditfield(app.ParameterRangePanel, 'text');
            app.xend.ValueChangedFcn = createCallbackFcn(app, @xendValueChanged, true);
            app.ystart = uieditfield(app.ParameterRangePanel, 'text');
            app.ystart.ValueChangedFcn = createCallbackFcn(app, @ystartValueChanged, true);
            app.yend = uieditfield(app.ParameterRangePanel, 'text');
            app.yend.ValueChangedFcn = createCallbackFcn(app, @yendValueChanged, true);
            app.xlabel = uilabel(app.ParameterRangePanel);
            app.xlabel.Text = 'x label';
            app.ylabel = uilabel(app.ParameterRangePanel);
            app.ylabel.Text = 'y label';
            app.startLabel = uilabel(app.ParameterRangePanel);
            app.startLabel.Text = 'start';
            app.endLabel = uilabel(app.ParameterRangePanel);
            app.endLabel.Text = 'end';
            app.GridpointsEditFieldLabel = uilabel(app.ParameterRangePanel);
            app.GridpointsEditFieldLabel.Text = 'Grid points';
            app.Gridpoints = uieditfield(app.ParameterRangePanel, 'text');
            app.Gridpoints.ValueChangedFcn = createCallbackFcn(app, @GridpointsValueChanged, true);
            app.Gridpoints.Tooltip = {'number of grid points to plot on each axis'};
            app.StabilityMapCoupledModeModelLabel = uilabel(app.UIFigure);
            app.StabilityMapCoupledModeModelLabel.Text = 'Stability Map - Coupled Mode Model';
            app.Panel = uipanel(app.UIFigure);
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load data from a file'};
            app.LoadButton.Text = 'Load';
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.Tooltip = {'Generate the stability map'};
            app.RunButton.Text = 'Run';
            app.SaveDataButton = uibutton(app.Panel, 'push');
            app.SaveDataButton.Enable = 'off';
            app.SaveDataButton.Tooltip = {'Save parameters and calculated data'};
            app.SaveDataButton.Text = 'Save Data';
            app.RevertButton = uibutton(app.Panel, 'push');
            app.RevertButton.Tooltip = {'Revert parameters to last run / saved or loaded'};
            app.RevertButton.Text = 'Revert';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StabilityMap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end